classdef SponAsMod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Reset_2                      matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        COSTAsLabel                  matlab.ui.control.Label
        GridCheckBox_3               matlab.ui.control.CheckBox
        GridCheckBox                 matlab.ui.control.CheckBox
        PlotCainERButton             matlab.ui.control.Button
        PlotIP3Button                matlab.ui.control.Button
        PlotCaButton                 matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        IP3VariablesTab              matlab.ui.container.Tab
        VpEditField                  matlab.ui.control.NumericEditField
        KIP3EditField                matlab.ui.control.NumericEditField
        KdegEditField                matlab.ui.control.NumericEditField
        VpSlider                     matlab.ui.control.Slider
        VpSliderLabel                matlab.ui.control.Label
        kIP3Slider                   matlab.ui.control.Slider
        kIP3SliderLabel              matlab.ui.control.Label
        KdegSlider                   matlab.ui.control.Slider
        KdegSliderLabel              matlab.ui.control.Label
        HillEqVariablesTab           matlab.ui.container.Tab
        KCaIEditField                matlab.ui.control.NumericEditField
        KCaAEditField                matlab.ui.control.NumericEditField
        K2EditField                  matlab.ui.control.NumericEditField
        KCaISlider                   matlab.ui.control.Slider
        KCaISliderLabel              matlab.ui.control.Label
        KCaASlider                   matlab.ui.control.Slider
        KCaASliderLabel              matlab.ui.control.Label
        K2Slider                     matlab.ui.control.Slider
        K2SliderLabel                matlab.ui.control.Label
        ExperimentalTracesDataTab_2  matlab.ui.container.Tab
        Tracesdata                   matlab.ui.control.Table
        VinSlider                    matlab.ui.control.Slider
        FlowofCalciumfromtheextracellularspacetothecytosolLabel  matlab.ui.control.Label
        uMLabel_2                    matlab.ui.control.Label
        uMsLabel_3                   matlab.ui.control.Label
        uMLabel                      matlab.ui.control.Label
        uMLabel_3                    matlab.ui.control.Label
        uMsLabel_2                   matlab.ui.control.Label
        kCaIEditField                matlab.ui.control.NumericEditField
        VM3EditField                 matlab.ui.control.NumericEditField
        k_outSlider                  matlab.ui.control.Slider
        k_outSliderLabel             matlab.ui.control.Label
        VM3Slider                    matlab.ui.control.Slider
        VM3SliderLabel               matlab.ui.control.Label
        KpSlider                     matlab.ui.control.Slider
        KpSliderLabel                matlab.ui.control.Label
        kPEditField                  matlab.ui.control.NumericEditField
        kCaAEditField                matlab.ui.control.NumericEditField
        VM2EditField                 matlab.ui.control.NumericEditField
        kfSlider                     matlab.ui.control.Slider
        kfSliderLabel                matlab.ui.control.Label
        VM2Slider                    matlab.ui.control.Slider
        VM2SliderLabel               matlab.ui.control.Label
        Reset                        matlab.ui.control.Button
        VinEditField                 matlab.ui.control.NumericEditField
        uMsLabel                     matlab.ui.control.Label
        CalciumOscillationSimulationToolforAstrocytesLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        PlotsTab                     matlab.ui.container.Tab
        GridCheckBox_2               matlab.ui.control.CheckBox
        PrintButton                  matlab.ui.control.StateButton
        IP3CAxes                     matlab.ui.control.UIAxes
        CaERAxes                     matlab.ui.control.UIAxes
        CaCAxes                      matlab.ui.control.UIAxes
        FullModelTab                 matlab.ui.container.Tab
        GridCheckBox_4               matlab.ui.control.CheckBox
        PlotfullmodelButton          matlab.ui.control.Button
        FMAxes                       matlab.ui.control.UIAxes
        FittingExpDataTab            matlab.ui.container.Tab
        CalculateMeanSquaredErrorButton  matlab.ui.control.Button
        AddTraceButton               matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        MeanSquaredErrorEditField    matlab.ui.control.NumericEditField
        FittingAxes                  matlab.ui.control.UIAxes
        Tab                          matlab.ui.container.Tab
        PlotMSEButton                matlab.ui.control.Button
        MSEAxes                      matlab.ui.control.UIAxes
        CytosolVariablesPanel        matlab.ui.container.Panel
        ERVariablesPanel             matlab.ui.container.Panel
    end

    
  %create a new internal set of parameters for the differential equations 
    properties (Access = public)
       Traces % Experimental astrocyte traces (Nick)
       params = ModelParameters(); %making the eq parmeters global variables
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %load traces
           load('astrocyte_traces.mat','traces_R','traces_S','traces_P');
           app.Traces = table(traces_R',traces_S',traces_P');
           app.Tracesdata.Data = app.Traces;
            
            %use stratup fnct to add classes and hope for the best
   
        import ModelParameters.*
        import ModelSystem.*
        import ode45.*
             

           %Design
        app.PlotsTab.FontName = 'Optima';
%         app.CalciumConcentrationintheERTab.FontName = 'Optima';
%         app.FullModelTab.FontName = 'Optima';
%         app.IP3ConcentrationTab.FontName = 'Optima';

        %fix the mf tabs

       % app.CaERAxes = uiaxes(app.PlotsTab);
         %plot ca in c


        end

        % Button pushed function: PlotCaButton
        function PlotCaButtonPushed(app, event)
            %plotting the trace
%           load('astrocyte_traces.mat','traces_R'); 
%         plot(app.CaCAxes,traces_R(3,:));
%   getting rid of it for the time being    
app.PlotCaButton.Visible = 'off';

%SCRIPT TO PLOT USING BUTTON AS A PLOT FUNCTION
            
            % Create an instance of ModelParameters and ModelSystem
            % dummy#1 added params as local variable
         %params = ModelParameters();
%         params.v_in = app.VinSlider.Value;
%         params.k_p = app.KpSlider.Value;
%         params.v_M2 = app.VM2Slider.Value;
%         params.v_M3 = app.VM3Slider.Value;
%         params.k_CaA = app.kfSlider.Value;
%         params.k_CaI = app.k_outSlider.Value;

%         %dummy#2 added params as global variables 
%         app.params.v_in = app.VinSlider.Value;
%         app.params.k_p = app.KpSlider.Value;
%         app.params.v_M2 = app.VM2Slider.Value;
%         app.params.v_M3 = app.VM3Slider.Value;
%         app.params.k_CaA = app.kfSlider.Value;
%         app.params.k_CaI = app.k_outSlider.Value;
%         mySys = ModelSystem(app.params);
%     

        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        %app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        %app.params.k_CaA = app.kfSlider.Value;
        %app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params); 


            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

        %Print current plot(?)
        %app.CaCAxes.NextPlot = 'add';

        end

        % Button pushed function: PlotIP3Button
        function PlotIP3ButtonPushed(app, event)
            app.PlotIP3Button.Visible = 'off';
            % Create an instance of ModelParameters and ModelSystem
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        %app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        %app.params.k_CaA = app.kfSlider.Value;
        %app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';

        end

        % Button pushed function: PlotCainERButton
        function PlotCainERButtonPushed(app, event)
            app.PlotCainERButton.Visible = 'off';     
            % Create an instance of ModelParameters and ModelSystem
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';

        end

        % Button pushed function: PlotfullmodelButton
        function PlotfullmodelButtonPushed(app, event)
%app.PlotCaButton.Visible = 'off';
   
            % Create an instance of ModelParameters and ModelSystem
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.FMAxes,t, data(:, 1),"Color",'magenta');
        plot(app.FMAxes, t, data(:, 2),'Color','cyan');
        plot(app.FMAxes, t, data(:, 3),'color','green');
        app.FMAxes.YLimMode = 'auto';

           
        end

        % Value changed function: VinSlider
        function VinSliderValueChanged(app, event)
        Vin = event.Value;            
        app.VinEditField.Value = Vin; %Connecting slider to EF
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
          %clearing the graphs
            cla(app.CaCAxes);
            cla(app.CaERAxes);
            cla(app.IP3CAxes);
            cla(app.FMAxes);
            cla(app.FittingAxes);
        end

        % Value changed function: VM3EditField
        function VM3EditFieldValueChanged(app, event)
           VM3 = event.Value;
           app.VM3Slider.Value = VM3;
        end

        % Value changed function: k_outSlider
        function k_outSliderValueChanged(app, event)
           kCAI = event.Value;
           app.kCaIEditField.Value = kCAI;
            
        end

        % Value changed function: VM3Slider
        function VM3SliderValueChanged(app, event)
            VM3 = event.Value;
            app.VM3EditField.Value = VM3;
   
        end

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            kp = event.Value;
            app.kPEditField.Value = kp;
            
        end

        % Value changed function: kPEditField
        function kPEditFieldValueChanged(app, event)
            kp = event.Value;
            app.KpSlider.Value = kp;
            
        end

        % Value changed function: kCaAEditField
        function kCaAEditFieldValueChanged(app, event)
            kCaA = event.Value;
            app.kfSlider.Value = kCaA;
            
        end

        % Value changed function: VM2EditField
        function VM2EditFieldValueChanged(app, event)
            VM2 = event.Value;
            app.VM2Slider.Value = VM2;
            
        end

        % Value changed function: kfSlider
        function kfSliderValueChanged(app, event)
            kCaA = event.Value;
            app.kCaAEditField.Value = kCaA;
            
        end

        % Value changed function: VM2Slider
        function VM2SliderValueChanged(app, event)
            VM2 = event.Value;
            app.VM2EditField.Value = VM2;
            
        end

        % Value changed function: VinEditField
        function VinEditFieldValueChanged(app, event)
            vin = event.Value;
            app.VinSlider.Value = vin;
            
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
%             if app.GridCheckBox.Value
%                 app.CaCAxes.XGrid = 'on';
%                 app.CaCAxes.YGrid = 'on';
%                 app.CaCAxes.XMinorGrid = 'on';
%                 app.CaCAxes.YMinorGrid = 'on';
%             else
%              app.CaCAxes.XGrid = 'off';
%              app.CaCAxes.YGrid = 'off';
%              app.CaCAxes.XMinorGrid = 'off';
%              app.CaCAxes.YMinorGrid = 'off';
%             end
            
        end

        % Value changed function: GridCheckBox_2
        function GridCheckBox_2ValueChanged(app, event)
             if app.GridCheckBox_2.Value
                app.IP3CAxes.XGrid = 'on';
                app.IP3CAxes.YGrid = 'on';
                app.IP3CAxes.XMinorGrid = 'on';
                app.IP3CAxes.YMinorGrid = 'on';
                app.CaERAxes.XGrid = 'on';
                app.CaERAxes.YGrid = 'on';
                app.CaERAxes.XMinorGrid = 'on';
                app.CaERAxes.YMinorGrid = 'on';
                app.CaCAxes.XGrid = 'on';
                app.CaCAxes.YGrid = 'on';
                app.CaCAxes.XMinorGrid = 'on';
                app.CaCAxes.YMinorGrid = 'on';
          else
             app.IP3CAxes.XGrid = 'off';
             app.IP3CAxes.YGrid = 'off';
             app.IP3CAxes.XMinorGrid = 'off';
            app.IP3CAxes.YMinorGrid = 'off';
            app.CaERAxes.XGrid = 'off';
             app.CaERAxes.YGrid = 'off';
             app.CaERAxes.XMinorGrid = 'off';
             app.CaERAxes.YMinorGrid = 'off';
             app.CaCAxes.XGrid = 'off';
             app.CaCAxes.YGrid = 'off';
             app.CaCAxes.XMinorGrid = 'off';
            app.CaCAxes.YMinorGrid = 'off';
            end

            
        end

        % Value changed function: GridCheckBox_3
        function GridCheckBox_3ValueChanged(app, event)
%            if app.GridCheckBox_3.Value
%                 app.CaERAxes.XGrid = 'on';
%                 app.CaERAxes.YGrid = 'on';
%                 app.CaERAxes.XMinorGrid = 'on';
%                 app.CaERAxes.YMinorGrid = 'on';
%           else
%              app.CaERAxes.XGrid = 'off';
%              app.CaERAxes.YGrid = 'off';
%              app.CaERAxes.XMinorGrid = 'off';
%              app.CaERAxes.YMinorGrid = 'off';
%             end
            
            
        end

        % Value changing function: VinSlider
        function VinSliderValueChanging(app, event)
       %plot ca in c
            app.params.v_in = event.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot Ca in C
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

         % Plot Ca in ER
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';


        %Plot ip3
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';
            
        end

        % Value changed function: PrintButton
        function PrintButtonValueChanged(app, event)
%             app.PrintButton 
            hold(app.CaCAxes) 
         %app.CaCAxes.NextPlot = 'add';
        
         %INSTEAD OF PRINTING JUST PLOT IT AND ADD NEXT PLOT ACTIVATION 

%              
%             if  app.PrintButton.Value 
%                hold(app.CaCAxes) 
%              else 
%                 waitfor(app.PrintButton,PrintButtonValueChanged(app,event)) 
% %                  PrintButtonValueChanged(app,)
% %                %app.PrintButton.Value = 'false';
%              end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
         app.Button.Visible = 'off';  
            
            %plotting the trace
        load('astrocyte_traces.mat','traces_S'); 
        plot(app.CaCAxes,traces_S(3,:),'LineWidth',2,'LineStyle','-');
        axis(app.CaCAxes,[0 280 -0.05 0.8]);

        plot(app.CaERAxes,traces_S(3,:),'LineWidth',2,'LineStyle','-');
        axis(app.CaERAxes,[0 280 -1 5]);

        plot(app.IP3CAxes,traces_S(3,:),'LineWidth',2,'LineStyle','-');
        axis(app.IP3CAxes,[0 280 -0.1 0.4]);

        %plot(app.CaERAxes,traces_R(3,:));
        %plot(app.IP3CAxes,traces_R(3,:));
        end

        % Value changing function: k_outSlider
        function k_outSliderValueChanging(app, event)
         %plot ca in c
          app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
        
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot Ca in C
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

         % Plot Ca in ER
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';


        %Plot ip3
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';
            
        end

        % Value changing function: VM3Slider
        function VM3SliderValueChanging(app, event)
            %plot ca in c
%            app.params = ModelParameters();
%         app.params.v_in = app.VinSlider;
%         app.params.k_p = app.KpSlider.Value;
%         app.params.v_M2 = app.VM2Slider.Value;
%         app.params.v_M3 = app.VM3Slider.Value;
%         app.params.k_CaA = app.kfSlider.Value;
%         app.params.k_CaI = app.k_outSlider.Value;
%         app.params.k_2 = app.K2Slider.Value;
%         app.params.k_deg = app.KdegSlider.Value;
%         app.params.k_IP3 = app.kIP3Slider.Value;
%         
%         mySys = ModelSystem(app.params);

        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        %app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        %app.params.k_CaA = app.kfSlider.Value;
        %app.params.k_CaI = app.k_outSlider.Value;
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot Ca in C
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

         % Plot Ca in ER
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';


        %Plot ip3
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';
            
        end

        % Value changing function: KpSlider
        function KpSliderValueChanging(app, event)
           %plot ca in c
          app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
       
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot Ca in C
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

         % Plot Ca in ER
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';


        %Plot ip3
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';
            
        end

        % Value changing function: kfSlider
        function kfSliderValueChanging(app, event)
              %plot ca in c
           app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
      
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
            %FIX THIS ERROR
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot Ca in C
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

         % Plot Ca in ER
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';


        %Plot ip3
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';
        end

        % Value changing function: VM2Slider
        function VM2SliderValueChanging(app, event)
            %plot ca in c
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
        
        mySys = ModelSystem(app.params);
    

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 600;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot Ca in C
        
        plot(app.CaCAxes,t, data(:, 1),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaCAxes.YLimMode = 'auto';

         % Plot Ca in ER
        
        plot(app.CaERAxes, t, data(:, 2),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.CaERAxes.YLimMode = 'auto';


        %Plot ip3
        plot(app.IP3CAxes, t, data(:, 3),'Color',[0.8500 0.3250 0.0980],'LineWidth',2);
        app.IP3CAxes.YLimMode = 'auto';
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
         app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
        mySys = ModelSystem(app.params); 

            % Set initial conditions and time
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 300;
        dt = 0.1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
[t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

            % Plot the results
        
        plot(app.FittingAxes,t, data(:, 1),'LineWidth',2);
        app.FittingAxes.YLimMode = 'auto';

        end

        % Button pushed function: AddTraceButton
        function AddTraceButtonPushed(app, event)
            %plotting the trace
        load('astrocyte_traces.mat','traces_S'); 
        plot(app.FittingAxes,traces_S(3,:),'Color',[0.8500 0.3250 0.0980],'LineWidth',2,'LineStyle','--');
        axis(app.FittingAxes,[0 280 -0.05 0.8]);
        end

        % Value changed function: MeanSquaredErrorEditField
        function MeanSquaredErrorEditFieldValueChanged(app, event)
           
           app.MeanSquaredErrorEditField.ValueDisplayFormat = 10;
        end

        % Button pushed function: CalculateMeanSquaredErrorButton
        function CalculateMeanSquaredErrorButtonPushed(app, event)
        %Variables tied to the app sliders    
         app.params = ModelParameters();
        app.params.v_in = app.VinSlider.Value;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
        mySys = ModelSystem(app.params); 

         %Set initial conditions 
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 288;
        dt = 1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
        [t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

        %Data
        CaData = [t, data(:, 1)];

        %Data of real trace
       load('astrocyte_traces.mat','traces_S'); 
        b = traces_S(3,:);
        a = [1:289];
        za = cat(2,a',b'); 


        %Mean square error
        MSE = mean((CaData-za).^2) ; 
        app.MeanSquaredErrorEditField.Value = MSE(:,2);
        end

        % Button pushed function: PlotMSEButton
        function PlotMSEButtonPushed(app, event)
              %Variables tied to the app sliders    
        app.params = ModelParameters();
        app.params.v_in = app.VinSlider;
        app.params.k_p = app.KpSlider.Value;
        app.params.v_M2 = app.VM2Slider.Value;
        app.params.v_M3 = app.VM3Slider.Value;
        app.params.k_CaA = app.kfSlider.Value;
        app.params.k_CaI = app.k_outSlider.Value;
        app.params.k_2 = app.K2Slider.Value;
        app.params.k_deg = app.KdegSlider.Value;
        app.params.k_IP3 = app.kIP3Slider.Value;
        
        mySys = ModelSystem(app.params); 


         %Set initial conditions 
        initial = [0.1; 1.5; 0.1]; % uM as a column vector 
        Tmax = 288;
        dt = 1;
        t = 0:dt:Tmax;

            % Solve the system of ODEs using ode45
        [t, data] = ode45(@(t, state) mySys.computeDeriv(state, t), t, initial'); %' transposing initial to use column vector

        %Data
        CaData = [t, data(:, 1)];

        %Data of real trace
       load('astrocyte_traces.mat','traces_S'); 
        b = traces_S(3,:);
        a = [1:289];
        za = cat(2,a',b'); 


        %Mean square error
        MSE = mean((CaData-za).^2) ; 
        plot(app.MSEAxes,MSE)
        end

        % Value changed function: KdegSlider
        function KdegSliderValueChanged(app, event)
             kdeg = event.Value;
            app.KdegEditField.Value = kdeg;
            
        end

        % Value changed function: KdegEditField
        function KdegEditFieldValueChanged(app, event)
            kdeg = event.Value;
            app.KdegSlider.Value = kdeg;
        end

        % Value changed function: VpEditField
        function VpEditFieldValueChanged(app, event)
             vp = event.Value;
            app.VpSlider.Value = vp;
        end

        % Value changed function: VpSlider
        function VpSliderValueChanged(app, event)
             vp = event.Value;
            app.VpEditField.Value = vp;
        end

        % Value changed function: KIP3EditField
        function KIP3EditFieldValueChanged(app, event)
            kip3 = event.Value;
            app.kIP3Slider.Value = kip3;
        end

        % Value changed function: kIP3Slider
        function kIP3SliderValueChanged(app, event)
             kip3 = event.Value;
            app.KIP3EditField.Value = kip3;
        end

        % Value changed function: KCaIEditField
        function KCaIEditFieldValueChanged(app, event)
             kcai = event.Value;
            app.KCaISlider.Value = kcai;
        end

        % Value changed function: KCaISlider
        function KCaISliderValueChanged(app, event)
            kcai = event.Value;
            app.KCaIEditField.Value = kcai;
        end

        % Value changed function: KCaASlider
        function KCaASliderValueChanged(app, event)
             kcaa = event.Value;
            app.KCaAEditField.Value = kcaa;
        end

        % Value changed function: KCaAEditField
        function KCaAEditFieldValueChanged(app, event)
             kcaa = event.Value;
            app.KCaASlider.Value = kcaa;
        end

        % Value changed function: K2EditField
        function K2EditFieldValueChanged(app, event)
             k2 = event.Value;
            app.K2Slider.Value = k2;
        end

        % Value changed function: K2Slider
        function K2SliderValueChanged(app, event)
             k2 = event.Value;
            app.K2Slider.Value = k2;
        end

        % Button pushed function: Reset_2
        function Reset_2ButtonPushed(app, event)
              %Resetting values
        app.VinSlider.Value = 0.05;
        app.KpSlider.Value = 0.3;
        app.VM2Slider.Value = 15;
        app.VM3Slider.Value = 40;
        app.kfSlider.Value = 0.15;
        app.k_outSlider.Value = 0.15;
        app.VinEditField.Value = 0.05;
        app.kPEditField.Value = 0.3;
        app.VM2EditField.Value = 15;
        app.VM3EditField.Value = 40;
        app.kCaAEditField.Value = 0.15;
        app.kCaIEditField.Value = 0.15;
        app.K2EditField.Value = 0.1;
        app.K2Slider.Value = 0.1;
        app.KCaAEditField.Value = 0.15;
        app.KCaASlider.Value = 0.15;
        app.KCaIEditField.Value = 0.15;
        app.KCaISlider.Value = 0.15;
        app.KdegEditField.Value = 0.08;
        app.KdegSlider.Value = 0.08;
        app.KIP3EditField.Value = 0.1;
        app.kIP3Slider.Value = 0.1;
        app.VpEditField.Value =  0.05;
        app.VpSlider.Value = 0.05;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ERVariablesPanel = uipanel(app.UIFigure);
            app.ERVariablesPanel.Title = 'ER Variables';
            app.CytosolVariablesPanel = uipanel(app.UIFigure);
            app.CytosolVariablesPanel.Title = 'Cytosol Variables ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.CaCAxes = uiaxes(app.PlotsTab);
            title(app.CaCAxes, {'Calcium Concentration in Cytosol'; ''})
            xlabel(app.CaCAxes, 'Time (s)')
            ylabel(app.CaCAxes, 'Ca (uM)')
            zlabel(app.CaCAxes, 'Z')
            app.CaCAxes.LineStyleOrder = {'-'};
            app.CaERAxes = uiaxes(app.PlotsTab);
            title(app.CaERAxes, 'Calcium Concentration in the ER')
            xlabel(app.CaERAxes, 'Time (s)')
            ylabel(app.CaERAxes, 'CaER (uM)')
            zlabel(app.CaERAxes, 'Z')
            app.CaERAxes.LineStyleOrder = {'-'};
            app.IP3CAxes = uiaxes(app.PlotsTab);
            title(app.IP3CAxes, 'IP3 Concentration')
            xlabel(app.IP3CAxes, 'Time (s)')
            ylabel(app.IP3CAxes, 'IP3 (uM)')
            zlabel(app.IP3CAxes, 'Z')
            app.IP3CAxes.LineStyleOrder = {'-'};
            app.PrintButton = uibutton(app.PlotsTab, 'state');
            app.PrintButton.ValueChangedFcn = createCallbackFcn(app, @PrintButtonValueChanged, true);
            app.PrintButton.Text = 'Print';
            app.GridCheckBox_2 = uicheckbox(app.PlotsTab);
            app.GridCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_2ValueChanged, true);
            app.GridCheckBox_2.Text = 'Grid';
            app.FullModelTab = uitab(app.TabGroup);
            app.FullModelTab.Title = 'Full Model';
            app.FMAxes = uiaxes(app.FullModelTab);
            title(app.FMAxes, 'Combined Model')
            xlabel(app.FMAxes, 'Time (s)')
            ylabel(app.FMAxes, 'Concentration (uM)')
            zlabel(app.FMAxes, 'Z')
            app.FMAxes.SubtitleFontWeight = 'bold';
            app.PlotfullmodelButton = uibutton(app.FullModelTab, 'push');
            app.PlotfullmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfullmodelButtonPushed, true);
            app.PlotfullmodelButton.Text = {'Plot full model'; ''};
            app.GridCheckBox_4 = uicheckbox(app.FullModelTab);
            app.GridCheckBox_4.Text = 'Grid';
            app.FittingExpDataTab = uitab(app.TabGroup);
            app.FittingExpDataTab.Title = 'Fitting Exp Data';
            app.FittingAxes = uiaxes(app.FittingExpDataTab);
            title(app.FittingAxes, 'Calcium Concentration In Astrocyte')
            zlabel(app.FittingAxes, 'Z')
            app.FittingAxes.LineStyleOrder = {'-'};
            app.MeanSquaredErrorEditField = uieditfield(app.FittingExpDataTab, 'numeric');
            app.MeanSquaredErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MeanSquaredErrorEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.FittingExpDataTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AddTraceButton = uibutton(app.FittingExpDataTab, 'push');
            app.AddTraceButton.ButtonPushedFcn = createCallbackFcn(app, @AddTraceButtonPushed, true);
            app.AddTraceButton.Text = 'Add Trace';
            app.CalculateMeanSquaredErrorButton = uibutton(app.FittingExpDataTab, 'push');
            app.CalculateMeanSquaredErrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMeanSquaredErrorButtonPushed, true);
            app.CalculateMeanSquaredErrorButton.Text = 'Calculate Mean Squared Error';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.MSEAxes = uiaxes(app.Tab);
            title(app.MSEAxes, 'MSE')
            xlabel(app.MSEAxes, 'X')
            ylabel(app.MSEAxes, 'Y')
            zlabel(app.MSEAxes, 'Z')
            app.PlotMSEButton = uibutton(app.Tab, 'push');
            app.PlotMSEButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMSEButtonPushed, true);
            app.PlotMSEButton.Text = 'Plot MSE';
            app.CalciumOscillationSimulationToolforAstrocytesLabel = uilabel(app.UIFigure);
            app.CalciumOscillationSimulationToolforAstrocytesLabel.Text = 'Calcium Oscillation Simulation Tool for Astrocytes';
            app.uMsLabel = uilabel(app.UIFigure);
            app.uMsLabel.Text = 'uM/s';
            app.VinEditField = uieditfield(app.UIFigure, 'numeric');
            app.VinEditField.ValueChangedFcn = createCallbackFcn(app, @VinEditFieldValueChanged, true);
            app.VinEditField.Value = 0.05;
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Clear plots';
            app.VM2SliderLabel = uilabel(app.UIFigure);
            app.VM2SliderLabel.Tooltip = {'Maximum flux of calcium ions out of the pump'};
            app.VM2SliderLabel.Text = 'VM2';
            app.VM2Slider = uislider(app.UIFigure);
            app.VM2Slider.Limits = [1 20];
            app.VM2Slider.ValueChangedFcn = createCallbackFcn(app, @VM2SliderValueChanged, true);
            app.VM2Slider.ValueChangingFcn = createCallbackFcn(app, @VM2SliderValueChanging, true);
            app.VM2Slider.Tooltip = {'Maximum flux of calcium ions from the cytosol to the ER'};
            app.VM2Slider.Value = 15;
            app.kfSliderLabel = uilabel(app.UIFigure);
            app.kfSliderLabel.Tooltip = {'Leak flux from the ER to the cytosol'; ''};
            app.kfSliderLabel.Text = 'kf';
            app.kfSlider = uislider(app.UIFigure);
            app.kfSlider.Limits = [0.1 0.3];
            app.kfSlider.ValueChangedFcn = createCallbackFcn(app, @kfSliderValueChanged, true);
            app.kfSlider.ValueChangingFcn = createCallbackFcn(app, @kfSliderValueChanging, true);
            app.kfSlider.Tooltip = {'Leak flux from the ER to the cytosol'; ''};
            app.kfSlider.Value = 0.15;
            app.VM2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VM2EditField.ValueChangedFcn = createCallbackFcn(app, @VM2EditFieldValueChanged, true);
            app.VM2EditField.Value = 15;
            app.kCaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.kCaAEditField.Limits = [0.05 0.3];
            app.kCaAEditField.ValueChangedFcn = createCallbackFcn(app, @kCaAEditFieldValueChanged, true);
            app.kCaAEditField.Value = 0.15;
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kPEditField.ValueChangedFcn = createCallbackFcn(app, @kPEditFieldValueChanged, true);
            app.kPEditField.Value = 0.3;
            app.KpSliderLabel = uilabel(app.UIFigure);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.UIFigure);
            app.KpSlider.Limits = [0.1 0.5];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KpSlider.ValueChangingFcn = createCallbackFcn(app, @KpSliderValueChanging, true);
            app.KpSlider.Tooltip = {'Concentration of Ca2+ required to produce a half-maximal response in the PLC positive feedback mechanism'};
            app.KpSlider.Value = 0.3;
            app.VM3SliderLabel = uilabel(app.UIFigure);
            app.VM3SliderLabel.Tooltip = {'Maximum flux of calcium ions into the cytosol'};
            app.VM3SliderLabel.Text = 'VM3';
            app.VM3Slider = uislider(app.UIFigure);
            app.VM3Slider.Limits = [5 300];
            app.VM3Slider.ValueChangedFcn = createCallbackFcn(app, @VM3SliderValueChanged, true);
            app.VM3Slider.ValueChangingFcn = createCallbackFcn(app, @VM3SliderValueChanging, true);
            app.VM3Slider.Tooltip = {'Maximum flux of calcium ions from ER to cytosol'};
            app.VM3Slider.Value = 40;
            app.k_outSliderLabel = uilabel(app.UIFigure);
            app.k_outSliderLabel.Tooltip = {'Rate of Ca2+ out of cytosol'; ''};
            app.k_outSliderLabel.Text = 'k_out';
            app.k_outSlider = uislider(app.UIFigure);
            app.k_outSlider.Limits = [0.1 0.3];
            app.k_outSlider.ValueChangedFcn = createCallbackFcn(app, @k_outSliderValueChanged, true);
            app.k_outSlider.ValueChangingFcn = createCallbackFcn(app, @k_outSliderValueChanging, true);
            app.k_outSlider.Tooltip = {'Inhibiting affinity of type-2 isoform'};
            app.k_outSlider.Value = 0.15;
            app.VM3EditField = uieditfield(app.UIFigure, 'numeric');
            app.VM3EditField.ValueChangedFcn = createCallbackFcn(app, @VM3EditFieldValueChanged, true);
            app.VM3EditField.Value = 40;
            app.kCaIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kCaIEditField.Value = 0.15;
            app.uMsLabel_2 = uilabel(app.UIFigure);
            app.uMsLabel_2.Text = 'uM/s';
            app.uMLabel_3 = uilabel(app.UIFigure);
            app.uMLabel_3.Text = 'uM';
            app.uMLabel = uilabel(app.UIFigure);
            app.uMLabel.Text = 'uM';
            app.uMsLabel_3 = uilabel(app.UIFigure);
            app.uMsLabel_3.Text = 'uM/s';
            app.uMLabel_2 = uilabel(app.UIFigure);
            app.uMLabel_2.Text = 'uM';
            app.FlowofCalciumfromtheextracellularspacetothecytosolLabel = uilabel(app.UIFigure);
            app.FlowofCalciumfromtheextracellularspacetothecytosolLabel.Tooltip = {'Flow of Calcium from the'; ' extracellular space to the cytosol '};
            app.FlowofCalciumfromtheextracellularspacetothecytosolLabel.Text = 'Vin';
            app.VinSlider = uislider(app.UIFigure);
            app.VinSlider.Limits = [0 0.5];
            app.VinSlider.ValueChangedFcn = createCallbackFcn(app, @VinSliderValueChanged, true);
            app.VinSlider.ValueChangingFcn = createCallbackFcn(app, @VinSliderValueChanging, true);
            app.VinSlider.Tooltip = {'Flow of Calcium from the'; ' extracellular space to the cytosol '};
            app.VinSlider.Value = 0.05;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.IP3VariablesTab = uitab(app.TabGroup2);
            app.IP3VariablesTab.Title = 'IP3 Variables';
            app.KdegSliderLabel = uilabel(app.IP3VariablesTab);
            app.KdegSliderLabel.Text = 'Kdeg';
            app.KdegSlider = uislider(app.IP3VariablesTab);
            app.KdegSlider.Limits = [0 1];
            app.KdegSlider.ValueChangedFcn = createCallbackFcn(app, @KdegSliderValueChanged, true);
            app.KdegSlider.Tooltip = {'Factor of degradation of IP3'};
            app.KdegSlider.Value = 0.08;
            app.kIP3SliderLabel = uilabel(app.IP3VariablesTab);
            app.kIP3SliderLabel.Text = 'kIP3';
            app.kIP3Slider = uislider(app.IP3VariablesTab);
            app.kIP3Slider.Limits = [0 0.5];
            app.kIP3Slider.ValueChangedFcn = createCallbackFcn(app, @kIP3SliderValueChanged, true);
            app.kIP3Slider.Tooltip = {'Concentration of IP3 required to create a half maximal response (Hill Eq)'};
            app.kIP3Slider.Value = 0.1;
            app.VpSliderLabel = uilabel(app.IP3VariablesTab);
            app.VpSliderLabel.Text = 'Vp';
            app.VpSlider = uislider(app.IP3VariablesTab);
            app.VpSlider.Limits = [0 0.1];
            app.VpSlider.ValueChangedFcn = createCallbackFcn(app, @VpSliderValueChanged, true);
            app.VpSlider.Value = 0.05;
            app.KdegEditField = uieditfield(app.IP3VariablesTab, 'numeric');
            app.KdegEditField.ValueChangedFcn = createCallbackFcn(app, @KdegEditFieldValueChanged, true);
            app.KdegEditField.Value = 0.08;
            app.KIP3EditField = uieditfield(app.IP3VariablesTab, 'numeric');
            app.KIP3EditField.ValueChangedFcn = createCallbackFcn(app, @KIP3EditFieldValueChanged, true);
            app.KIP3EditField.Value = 0.1;
            app.VpEditField = uieditfield(app.IP3VariablesTab, 'numeric');
            app.VpEditField.ValueChangedFcn = createCallbackFcn(app, @VpEditFieldValueChanged, true);
            app.VpEditField.Value = 0.05;
            app.HillEqVariablesTab = uitab(app.TabGroup2);
            app.HillEqVariablesTab.Title = 'Hill Eq Variables';
            app.K2SliderLabel = uilabel(app.HillEqVariablesTab);
            app.K2SliderLabel.Text = 'K2';
            app.K2Slider = uislider(app.HillEqVariablesTab);
            app.K2Slider.Limits = [0 0.3];
            app.K2Slider.ValueChangedFcn = createCallbackFcn(app, @K2SliderValueChanged, true);
            app.K2Slider.Value = 0.1;
            app.KCaASliderLabel = uilabel(app.HillEqVariablesTab);
            app.KCaASliderLabel.Text = 'KCaA';
            app.KCaASlider = uislider(app.HillEqVariablesTab);
            app.KCaASlider.Limits = [0 0.3];
            app.KCaASlider.ValueChangedFcn = createCallbackFcn(app, @KCaASliderValueChanged, true);
            app.KCaASlider.Value = 0.15;
            app.KCaISliderLabel = uilabel(app.HillEqVariablesTab);
            app.KCaISliderLabel.Text = 'KCaI';
            app.KCaISlider = uislider(app.HillEqVariablesTab);
            app.KCaISlider.Limits = [0 0.3];
            app.KCaISlider.ValueChangedFcn = createCallbackFcn(app, @KCaISliderValueChanged, true);
            app.KCaISlider.Value = 0.15;
            app.K2EditField = uieditfield(app.HillEqVariablesTab, 'numeric');
            app.K2EditField.ValueChangedFcn = createCallbackFcn(app, @K2EditFieldValueChanged, true);
            app.K2EditField.Value = 0.1;
            app.KCaAEditField = uieditfield(app.HillEqVariablesTab, 'numeric');
            app.KCaAEditField.ValueChangedFcn = createCallbackFcn(app, @KCaAEditFieldValueChanged, true);
            app.KCaAEditField.Value = 0.15;
            app.KCaIEditField = uieditfield(app.HillEqVariablesTab, 'numeric');
            app.KCaIEditField.ValueChangedFcn = createCallbackFcn(app, @KCaIEditFieldValueChanged, true);
            app.KCaIEditField.Value = 0.15;
            app.ExperimentalTracesDataTab_2 = uitab(app.TabGroup2);
            app.ExperimentalTracesDataTab_2.Title = 'Experimental Traces Data';
            app.Tracesdata = uitable(app.ExperimentalTracesDataTab_2);
            app.Tracesdata.ColumnName = {'Stellate'; 'Polygonal'; 'Reactive'};
            app.Tracesdata.RowName = {};
            app.PlotCaButton = uibutton(app.UIFigure, 'push');
            app.PlotCaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCaButtonPushed, true);
            app.PlotCaButton.Text = {'Plot Ca'; ''};
            app.PlotIP3Button = uibutton(app.UIFigure, 'push');
            app.PlotIP3Button.ButtonPushedFcn = createCallbackFcn(app, @PlotIP3ButtonPushed, true);
            app.PlotIP3Button.Text = {'Plot IP3'; ''};
            app.PlotCainERButton = uibutton(app.UIFigure, 'push');
            app.PlotCainERButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCainERButtonPushed, true);
            app.PlotCainERButton.Text = 'Plot Ca in ER';
            app.GridCheckBox = uicheckbox(app.UIFigure);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Visible = 'off';
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox_3 = uicheckbox(app.UIFigure);
            app.GridCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_3ValueChanged, true);
            app.GridCheckBox_3.Visible = 'off';
            app.GridCheckBox_3.Text = 'Grid';
            app.COSTAsLabel = uilabel(app.UIFigure);
            app.COSTAsLabel.Text = 'COSTAs';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Reset_2 = uibutton(app.UIFigure, 'push');
            app.Reset_2.ButtonPushedFcn = createCallbackFcn(app, @Reset_2ButtonPushed, true);
            app.Reset_2.Text = {'Reset Variables'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SponAsMod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end